classdef TrabalhoFinal_Visao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        listaImagens       matlab.ui.control.ListBox
        ImagemOriginal     matlab.ui.control.UIAxes
        BotaoManual        matlab.ui.control.Button
        textoResultado     matlab.ui.control.Label
        ImagemFinal        matlab.ui.control.UIAxes
        BotaoRN            matlab.ui.control.Button
        ResultadoLabel     matlab.ui.control.Label
        TituloLabel        matlab.ui.control.Label
        BotoesLabel        matlab.ui.control.Label
        TituloIcone        matlab.ui.control.Image
        listaImagensLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        CaminhoPastaImagens = 'dataset';
        Imagens = {};
        ImagemAtual = '';
    end
    
    methods (Access = private)
        
        function carregarImagens(app)
            % Carregar as imagens ".jpg" da pasta
            if isfolder(app.CaminhoPastaImagens)
                arquivoImagem = dir(fullfile(app.CaminhoPastaImagens, '*.jpg'));
                app.Imagens = {arquivoImagem.name};
                app.listaImagens.Items = app.Imagens;
            else
                disp('Pasta de imagens não encontrada.');
            end
        end
        
        function mostrarImagemOriginal(app)
            app.textoResultado.Text = ''; % Limpar o conteúdo do textoResultado
            cla(app.ImagemFinal); % Limpar a exibição da imagem final
    
            imagemSelecionada = app.listaImagens.Value;
            if ~isempty(imagemSelecionada)
                CaminhoImagem = fullfile(app.CaminhoPastaImagens, imagemSelecionada);
                
                % Ler e exibir a imagem
                app.ImagemAtual = imread(CaminhoImagem);
                imshow(app.ImagemAtual, 'Parent', app.ImagemOriginal);
            end
        end
        
        % Função para processar imagem com operações morfológicas
        function processarImagemManual(app, imagem)
            imagem = imresize(imagem, [300, 300]);
            
            verificarFundoPreto(app, imagem);
        end
        
        function verificarFundoPreto(app, imagem)
            countPixelsPreto = 0;    
    
            % Fazer a verificação dos pixels
            for i = 1:size(imagem, 1)
                for j = 1:size(imagem, 2)
                    % Se o pixel for preto, incrementa a contagem
                    if imagem(i, j,:) == 0
                       countPixelsPreto = countPixelsPreto + 1;
                    end
                end
            end
        
            % Se tem fundo preto pega a imagem do limão
            if countPixelsPreto > 10000
                % Passar para escala de cinza
                imagem_gray = rgb2gray(imagem);
        
                % Binarizar a imagem
                threshold = graythresh(imagem_gray);
                imagem_binaria = imbinarize(imagem_gray, threshold);
                
                % Exibir a imagem original e desenhar o contorno no mesmo eixo
                imshow(imagem, 'Parent', app.ImagemFinal); 
                hold(app.ImagemFinal, 'on'); 
                
                % Encontrar as bordas dos contornos
                [B, ~] = bwboundaries(imagem_binaria, 'noholes');
                
                % Desenhar cada contorno manualmente
                for k = 1:length(B)
                    boundary = B{k};
                    plot(app.ImagemFinal, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2);
                end
                
                hold(app.ImagemFinal, 'off');
                
                % Chamada da função para verificar condição do limão
                verificarCondicaoLimao(app, imagem);
                 
            % Se não tiver, verificar se tem limão ou não e depois cortar o limão
            else
                % Binarizar a imagem (acima de 150 valores brancos (1); abaixo pretos (0))
                imagemBin = imagem > 150;
        
                % Chamada da função para vericar se a imagem tem limão ou não
                verificarLimao(app, imagemBin, imagem);
            end
        end
        
        function verificarLimao(app, imagemBinarizada, imagemOriginal)
            % Conta o número de pixels brancos na imagem binarizada
            numPixelsBranco = sum(imagemBinarizada(:));
            
            % Se o número de pixels brancos for menor que o limite, a imagem contém um limão
            if numPixelsBranco < 150000
                removerFundoImagem(app, imagemOriginal);
            % Se não, é apenas o fundo cinza na imagem  
            else
                app.textoResultado.Text = '  Imagem sem limão.';
                app.textoResultado.FontColor = [1, 0, 0]; % Cor vermelha
            end
        end
        
        function removerFundoImagem(app, imagem)
            % Converter a imagem para o espaço de cor HSV
            imagemHsv = rgb2hsv(imagem);
        
            % Segmentar o limão com base na cor forte (amarelo)
            limiarMatiz     = (imagemHsv(:,:,1) >= 0.0  & imagemHsv(:,:,1) <= 0.15);
            limiarSaturacao = (imagemHsv(:,:,2) >= 0.3  & imagemHsv(:,:,2) <= 1);
            limiarBrilho    = (imagemHsv(:,:,3) >= 0.2 & imagemHsv(:,:,3) <= 1);
        
            % Combinar os limiares
            mascaraLimao = limiarMatiz & limiarSaturacao & limiarBrilho;
                
            % Preencher buracos que foram retirados
            mascaraLimao = imfill(mascaraLimao, 'holes');
            
            % Remover pequenas áreas ruidosas
            mascaraLimao = bwareaopen(mascaraLimao, 50);
        
            % Refinar a máscara com operações morfológicas
            se_large = strel('disk', 5);
            se_small = strel('disk', 3);
            mascaraLimao = imclose(mascaraLimao, se_large);
            mascaraLimao = imfill(mascaraLimao, 'holes');
            mascaraLimao = imopen(mascaraLimao, se_small);
            mascaraLimao = bwareaopen(mascaraLimao, 100);
        
            % Aplicar a máscara na imagem original
            imagemTratada = imagem;
            imagemTratada(repmat(~mascaraLimao, [1, 1, 3])) = 0;
        
            % Identificar os contornos das regiões conectadas
            [B, L] = bwboundaries(mascaraLimao, 'noholes');
        
            % Extrair propriedades das regiões
            estados = regionprops(L, 'Area');
        
            % Definir um limiar apropriado para a área
            threshold_area = 100; % Ajuste conforme necessário
        
            % Visualizar as áreas problemáticas identificadas
            imshow(imagemTratada, 'Parent', app.ImagemFinal);
            hold(app.ImagemFinal, 'on'); 
            for k = 1:length(B)
                boundary = B{k};
                if estados(k).Area > threshold_area
                    plot(app.ImagemFinal, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2);
                end
            end
            hold(app.ImagemFinal, 'off');
            
            % Chamada da função para verificar condição do limão
            verificarCondicaoLimao(app, imagemTratada);
        end

        % Função para verificar a condição do limão (bom ou com mofo)
        function verificarCondicaoLimao(app, imagem)
            % Extrair os canais vermelho, verde e azul da imagem
            Ir = imagem(:,:,1);
            Ig = imagem(:,:,2);
            Ib = imagem(:,:,3);
            
            % Criar uma máscara para excluir os pixels pretos (fundo)
            mascaraImagem = ~(Ir == 0 & Ig == 0 & Ib == 0);
            
            % Aplicar a máscara aos canais de cor
            Ir_valid = Ir(mascaraImagem);
            Ig_valid = Ig(mascaraImagem);
            Ib_valid = Ib(mascaraImagem);
            
            % Calcular a média dos valores dos pixels para cada canal, ignorando os pixels pretos
            mediaR = mean(Ir_valid(:));
            mediaG = mean(Ig_valid(:));
            mediaB = mean(Ib_valid(:));
            
            % Definir os limites para um limão bom
            limiteR = 128;
            limiteG = 105;
            limiteB = 60;
            
            % Verificar a condição do limão com base nos limites
            if mediaR > limiteR && mediaG > limiteG && mediaB < limiteB
                app.textoResultado.Text = '  O limão está em boa qualidade.';
                app.textoResultado.FontColor = [0, 0.5, 0]; % Cor verde escura
            else
                app.textoResultado.Text = '  O limão está com mofo.';
                app.textoResultado.FontColor = [1, 0, 0]; % Cor vermelha
            end

        end
        
        function classificarImagem(app, imagem)
           % Redimensionar a imagem
           imagem = imresize(imagem, [300, 300]);
            
           % Mostrar a imagem
           imshow(imagem, 'Parent', app.ImagemFinal); 
            
           % Carregar modelo da rede neural treinada
           load('classificacao_limao.mat', 'net');
            
            % Fazer a predição na imagem específica
            resultadoImagemSelecionada = classify(net, imagem);
            resultado = char(resultadoImagemSelecionada);
            
           if strcmpi(resultado, 'good_quality')
               app.textoResultado.Text = ' Limão com boa qualidade.';
               app.textoResultado.FontColor = [0, 0.5, 0]; % Cor verde escura
            elseif strcmpi(resultado, 'bad_quality')
               app.textoResultado.Text = ' Limão com mofo.';
               app.textoResultado.FontColor = [1, 0, 0]; % Cor vermelha
            elseif strcmpi(resultado, 'empty_background')
               app.textoResultado.Text = ' Imagem sem limão.';
               app.textoResultado.FontColor = [1, 0, 0]; 
            end
        end
        
        function limparVariaveis(app)
            % Limpar resultado e colocar fonte preta
            app.textoResultado.Text = '';
            app.textoResultado.FontColor = [0, 0, 0];
            
            % Limpar a exibição da imagem final
            cla(app.ImagemFinal); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            carregarImagens(app);
        end

        % Value changed function: listaImagens
        function listaImagensValueChanged(app, event)
            mostrarImagemOriginal(app);
        end

        % Button pushed function: BotaoManual
        function BotaoManualPushed(app, event)
            if isempty(app.ImagemAtual)
                errordlg('Nenhuma imagem foi carregada, por favor, selecione uma imagem.', 'Erro');
                return;
            end
            
            limparVariaveis(app);
            processarImagemManual(app, app.ImagemAtual);
        end

        % Button pushed function: BotaoRN
        function BotaoRNButtonPushed(app, event)
            if isempty(app.ImagemAtual)
                errordlg('Nenhuma imagem foi carregada, por favor, selecione uma imagem.', 'Erro');
                return;
            end

            limparVariaveis(app);
            classificarImagem(app, app.ImagemAtual);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.listaImagens = uilistbox(app.UIFigure);
            app.listaImagens.ValueChangedFcn = createCallbackFcn(app, @listaImagensValueChanged, true);
            app.ImagemOriginal = uiaxes(app.UIFigure);
            title(app.ImagemOriginal, 'Original')
            xlabel(app.ImagemOriginal, '')
            ylabel(app.ImagemOriginal, '')
            app.BotaoManual = uibutton(app.UIFigure, 'push');
            app.BotaoManual.ButtonPushedFcn = createCallbackFcn(app, @BotaoManualPushed, true);
            app.BotaoManual.Text = 'Manual';
            app.textoResultado = uilabel(app.UIFigure);
            app.textoResultado.Text = '';
            app.ImagemFinal = uiaxes(app.UIFigure);
            title(app.ImagemFinal, 'Final')
            xlabel(app.ImagemFinal, '')
            ylabel(app.ImagemFinal, '')
            app.BotaoRN = uibutton(app.UIFigure, 'push');
            app.BotaoRN.ButtonPushedFcn = createCallbackFcn(app, @BotaoRNButtonPushed, true);
            app.BotaoRN.Text = 'Rede neural';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado:';
            app.TituloLabel = uilabel(app.UIFigure);
            app.TituloLabel.Text = 'Controle de qualidade em limões';
            app.BotoesLabel = uilabel(app.UIFigure);
            app.BotoesLabel.Text = 'Selecione o tipo de operação para detecção:';
            app.TituloIcone = uiimage(app.UIFigure);
            app.TituloIcone.ImageSource = 'lemon_122997.png';
            app.listaImagensLabel = uilabel(app.UIFigure);
            app.listaImagensLabel.Text = 'Selecione a imagem:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrabalhoFinal_Visao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end