classdef TrabalhoFinal_Visao_Augusto_Gabriel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControledequalidadeemlimesUIFigure  matlab.ui.Figure
        listaImagens             matlab.ui.control.ListBox
        ImagemOriginal           matlab.ui.control.UIAxes
        BotaoManual              matlab.ui.control.Button
        textoResultado           matlab.ui.control.Label
        ImagemFinal              matlab.ui.control.UIAxes
        BotaoRN                  matlab.ui.control.Button
        ResultadodaoperaoLabel   matlab.ui.control.Label
        TituloLabel              matlab.ui.control.Label
        BotoesMorfoLabel         matlab.ui.control.Label
        TituloIcone              matlab.ui.control.Image
        listaImagensLabel        matlab.ui.control.Label
        BotaoManualTodasimagens  matlab.ui.control.Button
        BotoesRNLabel            matlab.ui.control.Label
        BotaoRNTodasimagens      matlab.ui.control.Button
    end

    %% Trabalho final de Visão por Computador
    % Controle de qualidade em frutos (limões)
    % Elaborado por: Augusto Dessanti e Gabriel Pimentel

    properties (Access = private)
        CaminhoPastaImagens = 'dataset';    % Caminho da pasta com imagens
        Imagens = {};                       % Vetor de imagens
        ImagemAtual = '';                   % Imagem atual
        countBoaQualidade = 0;              % Contagem de limões bons
        countMofo = 0;                      % Contagem de limões com mofo
        countSemLimao = 0;                  % Contagem de imagens sem limão
        flagFunction = 0;                   % Flag para indicar se tem uma ou mais imagens
    end
    
    methods (Access = private)
        % Função para carregar as imagens na lista
        function carregarImagens(app)
            % Carregar as imagens ".jpg" da pasta
            if isfolder(app.CaminhoPastaImagens)
                arquivoImagem = dir(fullfile(app.CaminhoPastaImagens, '*.jpg'));
                app.Imagens = {arquivoImagem.name};
                app.listaImagens.Items = app.Imagens;
            else
                disp('Pasta de imagens não encontrada.');
            end
        end
        
        % Função para mostrar a imagem no plot original
        function mostrarImagemOriginal(app)
            imagemSelecionada = app.listaImagens.Value;
            if ~isempty(imagemSelecionada)
                CaminhoImagem = fullfile(app.CaminhoPastaImagens, imagemSelecionada);
                
                % Ler e exibir a imagem
                app.ImagemAtual = imread(CaminhoImagem);
                imshow(app.ImagemAtual, 'Parent', app.ImagemOriginal);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%% OPERAÇÕES MORFOLÓGICAS %%%%%%%%%%%%%%%%%%%%%%
        % Função para processar uma imagem com operações morfológicas
        function processarImagemManual(app, imagem)
            imagem = imresize(imagem, [300, 300]);
            
            % Valor 1: informar que é apenas uma imagem
            app.flagFunction = 1; 
            resultado = verificarFundoPreto(app, imagem);
            
            % Mostrar o resultado em tela de acordo com a imagem
            if strcmp(resultado, 'Limão com boa qualidade')
               app.textoResultado.Text = ' Limão com boa qualidade.';
               app.textoResultado.FontColor = [0, 0.5, 0]; % Cor verde escura
            elseif strcmp(resultado, 'Limão com mofo')
               app.textoResultado.Text = ' Limão com mofo.';
               app.textoResultado.FontColor = [1, 0, 0]; % Cor vermelha
            elseif strcmp(resultado, 'Imagem sem limão')
               app.textoResultado.Text = ' Imagem sem limão.';
               app.textoResultado.FontColor = [1, 0, 0]; 
            end
        end
        
        % Função para processar todas as imagens com operações morfológicas
        function processarTodasImagens(app)
            % Inicializar array para armazenar os resultados
            resultados = cell(length(app.Imagens), 2);
            
            % Criar a barra de progresso
            d = uiprogressdlg(app.ControledequalidadeemlimesUIFigure, 'Title', 'Processando Imagens', 'Message', 'Iniciando...', 'Indeterminate', 'off', 'Cancelable', 'on');
            
            % Percorrer todas as imagens e executar as funções
            for i = 1:length(app.Imagens)
                % Verificar se o usuário cancelou a operação
                if d.CancelRequested
                    break;
                end
                
                imagemSelecionada = app.Imagens{i};
                CaminhoImagem = fullfile(app.CaminhoPastaImagens, imagemSelecionada);
                
                % Ler a imagem e redimensionar
                imagem = imread(CaminhoImagem);
                imagem = imresize(imagem, [300, 300]);
                
                % Salvar o nome da imagem na primeira coluna
                resultados{i, 1} = imagemSelecionada;
                
                % Verificar a imagem e obter o resultado
                resultado = verificarFundoPreto(app, imagem);
                
                % Salvar o resultado na segunda coluna
                resultados{i, 2} = resultado;
                
                % Atualizar contadores das imagens
                if strcmp(resultado, 'Limão com boa qualidade')
                    app.countBoaQualidade = app.countBoaQualidade + 1;
                elseif strcmp(resultado, 'Limão com mofo')
                    app.countMofo = app.countMofo + 1;
                elseif strcmp(resultado, 'Imagem sem limão')
                    app.countSemLimao = app.countSemLimao + 1;
                end
                
                % Atualizar a barra de progresso
                d.Value = i / length(app.Imagens);
                d.Message = sprintf('Processando imagem %d de %d...', i, length(app.Imagens));
            end
            
            % Fechar a barra de progresso
            close(d);
            
            % Exibir os resultados na interface
            app.textoResultado.Text = sprintf('Limões de boa qualidade: %d\nLimões com mofo: %d\nImagens sem limão: %d', ...
                app.countBoaQualidade, app.countMofo, app.countSemLimao);
            app.textoResultado.FontColor = [0, 0, 0];
            
            % Salvar os resultados em um arquivo Excel ".xlsx"
            filename = 'resultados.xlsx';
            headers = {'Imagem', 'Resultado'};
            xlswrite(filename, [headers; resultados]);
        end
        
        % Função para verificar se imagem tem fundo preto ou não 
        function resultado = verificarFundoPreto(app, imagem)
            countPixelsPreto = 0;    
    
            % Fazer a verificação dos pixels
            for i = 1:size(imagem, 1)
                for j = 1:size(imagem, 2)
                    % Se o pixel for preto, incrementa a contagem
                    if imagem(i, j,:) == 0
                       countPixelsPreto = countPixelsPreto + 1;
                    end
                end
            end
        
            % Se tem fundo preto pega a imagem do limão
            if countPixelsPreto > 10000
                % Se flag é igual a 1, então tem apenas uma imagem
                % Assim, pode plotar a imagem na interface
                if app.flagFunction == 1
                    % Passar para escala de cinza
                    imagem_gray = rgb2gray(imagem);
            
                    % Binarizar a imagem
                    threshold = graythresh(imagem_gray);
                    imagem_binaria = imbinarize(imagem_gray, threshold);
                    
                    % Exibir a imagem original e desenhar o contorno
                    imshow(imagem, 'Parent', app.ImagemFinal); 
                    hold(app.ImagemFinal, 'on'); 
                    
                    % Encontrar as bordas dos contornos
                    [B, ~] = bwboundaries(imagem_binaria, 'noholes');
                    
                    % Desenhar cada contorno
                    for k = 1:length(B)
                        boundary = B{k};
                        plot(app.ImagemFinal, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2);
                    end
                    hold(app.ImagemFinal, 'off');
                end
                
                % Função para verificar condição do limão
                resultado = verificarCondicaoLimao(app, imagem);
                 
            % Se não tiver, verificar se tem limão ou não e depois cortar o limão
            else
                % Binarizar a imagem (acima de 150 valores brancos (1); abaixo pretos (0))
                imagemBin = imagem > 150;
        
                % Chamada da função para vericar se a imagem tem limão ou não
                resultado = verificarLimao(app, imagemBin, imagem);
            end
        end
        
        % Função para verificar se tem limão ou não
        function resultado = verificarLimao(app, imagemBinarizada, imagemOriginal)
            % Conta o número de pixels brancos na imagem binarizada
            numPixelsBranco = sum(imagemBinarizada(:));
            
            % Se o número de pixels brancos for menor que o limite, a imagem contém um limão
            if numPixelsBranco < 150000
                resultado = removerFundoImagem(app, imagemOriginal);
            % Se não, é apenas o fundo cinza na imagem  
            else
                resultado = 'Imagem sem limão';
            end
        end
        
        % Função para remover o fundo da imagem (imagem com fundo cinza)
        function resultado = removerFundoImagem(app, imagem)
            % Converter a imagem para o espaço de cor HSV
            imagemHsv = rgb2hsv(imagem);
        
            % Segmentar o limão com base no amarelo (cor forte)
            limiarMatiz     = (imagemHsv(:,:,1) >= 0.0  & imagemHsv(:,:,1) <= 0.15);
            limiarSaturacao = (imagemHsv(:,:,2) >= 0.3  & imagemHsv(:,:,2) <= 1);
            limiarBrilho    = (imagemHsv(:,:,3) >= 0.2 & imagemHsv(:,:,3) <= 1);
        
            % Combinar os limiares
            mascaraLimao = limiarMatiz & limiarSaturacao & limiarBrilho;
                
            % Preencher buracos que foram retirados
            mascaraLimao = imfill(mascaraLimao, 'holes');
            
            % Remover pequenas áreas ruidosas
            mascaraLimao = bwareaopen(mascaraLimao, 50);
        
            % Refinar a máscara com operações morfológicas
            se_large = strel('disk', 5);
            se_small = strel('disk', 3);
            mascaraLimao = imclose(mascaraLimao, se_large);
            mascaraLimao = imfill(mascaraLimao, 'holes');
            mascaraLimao = imopen(mascaraLimao, se_small);
            mascaraLimao = bwareaopen(mascaraLimao, 100);
        
            % Aplicar a máscara na imagem original
            imagemTratada = imagem;
            imagemTratada(repmat(~mascaraLimao, [1, 1, 3])) = 0;
        
            % Se flag = 1, tem apenas uma imagem e exibe no plot
            if app.flagFunction == 1
                % Identificar os contornos das regiões conectadas
                [B, L] = bwboundaries(mascaraLimao, 'noholes');
            
                % Extrair propriedades das regiões
                estados = regionprops(L, 'Area');
            
                % Definir um limiar apropriado para a área
                threshold_area = 100;
            
                % Visualizar as áreas problemáticas identificadas
                imshow(imagemTratada, 'Parent', app.ImagemFinal);
                hold(app.ImagemFinal, 'on'); 
                for k = 1:length(B)
                    boundary = B{k};
                    if estados(k).Area > threshold_area
                        plot(app.ImagemFinal, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2);
                    end
                end
                hold(app.ImagemFinal, 'off');
            end
            
            % Função para verificar condição do limão
            resultado = verificarCondicaoLimao(app, imagemTratada);
        end

        % Função para verificar a condição do limão (bom ou com mofo)
        function resultado = verificarCondicaoLimao(~, imagem)
            % Extrair os canais R, G, B da imagem
            Ir = imagem(:,:,1);
            Ig = imagem(:,:,2);
            Ib = imagem(:,:,3);
            
            % Criar uma máscara para excluir os pixels pretos (fundo)
            mascaraImagem = ~(Ir == 0 & Ig == 0 & Ib == 0);
            
            % Aplicar a máscara aos canais de cor
            Ir_valido = Ir(mascaraImagem);
            Ig_valido = Ig(mascaraImagem);
            Ib_valido = Ib(mascaraImagem);
            
            % Calcular a média dos valores dos pixels para cada canal, ignorando os pixels pretos
            mediaR = mean(Ir_valido(:));
            mediaG = mean(Ig_valido(:));
            mediaB = mean(Ib_valido(:));
            
            % Limites de R, G, B para um limão bom com base nas imagens
            limiteR = 128;
            limiteG = 105;
            limiteB = 60;
            
            % Verificar a condição do limão com base nos limites
            if mediaR > limiteR && mediaG > limiteG && mediaB < limiteB
                resultado = 'Limão com boa qualidade';
            else
                resultado = 'Limão com mofo';
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%% REDES NEURAIS %%%%%%%%%%%%%%%%%%%%%%%%%%
        % Função para processar uma imagem com a rede neural treinada
        function processarImagemRN (app, imagem)
           % Redimensionar a imagem
           imagem = imresize(imagem, [300, 300]);
            
           % Mostrar a imagem
           imshow(imagem, 'Parent', app.ImagemFinal); 
           
           % Chamada da função para classificar o limão
           resultado = classificarLimao(app, imagem);
            
           % Mostrar o resultado em tela de acordo com a imagem
           if strcmpi(resultado, 'good_quality')
               app.textoResultado.Text = ' Limão com boa qualidade.';
               app.textoResultado.FontColor = [0, 0.5, 0]; % Cor verde escura
            elseif strcmpi(resultado, 'bad_quality')
               app.textoResultado.Text = ' Limão com mofo.';
               app.textoResultado.FontColor = [1, 0, 0]; % Cor vermelha
            elseif strcmpi(resultado, 'empty_background')
               app.textoResultado.Text = ' Imagem sem limão.';
               app.textoResultado.FontColor = [1, 0, 0]; 
            end
        end
        
        % Função para fazer predição do limão com a rede treinada
        function resultado = classificarLimao(~, imagem)
            % Carregar modelo da rede neural treinada
            load('classificacao_limao.mat', 'net');
            
            % Fazer a predição na imagem específica
            resultadoImagemSelecionada = classify(net, imagem);
            resultado = char(resultadoImagemSelecionada);
        end
        
        % Função para classificar todas as imagens com a rede treinada
        function classificarTodasImagens(app)
            % Inicializar array para armazenar os resultados
            resultados = cell(length(app.Imagens), 2);
            
            % Criar a barra de progresso
            d = uiprogressdlg(app.ControledequalidadeemlimesUIFigure, 'Title', 'Classificando imagens', 'Message', 'Iniciando...', 'Indeterminate', 'off', 'Cancelable', 'on');
            
            % Percorrer todas as imagens e executar as funções
            for i = 1:length(app.Imagens)
                % Verificar se o usuário cancelou a operação
                if d.CancelRequested
                    break;
                end
                
                imagemSelecionada = app.Imagens{i};
                CaminhoImagem = fullfile(app.CaminhoPastaImagens, imagemSelecionada);
                
                % Ler a imagem e redimensionar
                imagem = imread(CaminhoImagem);
                imagem = imresize(imagem, [300, 300]);
                
                % Salvar o nome da imagem na primeira coluna
                resultados{i, 1} = imagemSelecionada;
                
                % Verificar a imagem e obter o resultado       
                resultado = classificarLimao(app, imagem);
                
                % Salvar o resultado na segunda coluna
                resultados{i, 2} = resultado;
                                   
                % Atualizar contadores das imagens
                if strcmpi(resultado, 'good_quality')
                    app.countBoaQualidade = app.countBoaQualidade + 1;
                elseif strcmpi(resultado, 'bad_quality')
                    app.countMofo = app.countMofo + 1;
                elseif strcmpi(resultado, 'empty_background')
                    app.countSemLimao = app.countSemLimao + 1;
                end
                
                % Atualizar a barra de progresso
                d.Value = i / length(app.Imagens);
                d.Message = sprintf('Classificando imagem %d de %d...', i, length(app.Imagens));
            end
            
            % Fechar a barra de progresso
            close(d);
            
            % Exibir os resultados na interface
            app.textoResultado.Text = sprintf('Limões de boa qualidade: %d\nLimões com mofo: %d\nImagens sem limão: %d', ...
                app.countBoaQualidade, app.countMofo, app.countSemLimao);
            app.textoResultado.FontColor = [0, 0, 0];
            
            % Salvar os resultados em um arquivo Excel ".xlsx"
            filename = 'resultados_rede_neural.xlsx';
            headers = {'Imagem', 'Resultado'};
            xlswrite(filename, [headers; resultados]);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Função para limpar os conteúdos antes de executar as funções
        function limparVariaveis(app)
            % Limpar resultado e colocar fonte preta
            app.textoResultado.Text = '';
            app.textoResultado.FontColor = [0, 0, 0];
            
            % Zerar os contadores
            app.countBoaQualidade = 0;
            app.countMofo = 0;
            app.countSemLimao = 0;
            app.flagFunction = 0;
            
            % Limpar a exibição da imagem final
            cla(app.ImagemFinal); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            carregarImagens(app);
        end

        % Value changed function: listaImagens
        function listaImagensValueChanged(app, event)
            mostrarImagemOriginal(app);
        end

        % Button pushed function: BotaoManual
        function BotaoManualPushed(app, event)
            % Se não selecionou nenhuma imagem, mostrar erro em tela
            if isempty(app.ImagemAtual)
                errordlg('Nenhuma imagem foi carregada, por favor, selecione uma imagem.', 'Erro');
                return;
            end
            
            % Se selecionou, limpar as variáveis e processar a imagem
            limparVariaveis(app);
            processarImagemManual(app, app.ImagemAtual);
        end

        % Button pushed function: BotaoManualTodasimagens
        function BotaoManualTodasimagensButtonPushed(app, event)
            % Se pasta existir, entra na condição
            if isfolder(app.CaminhoPastaImagens)
                arquivoImagem = dir(fullfile(app.CaminhoPastaImagens, '*.jpg'));
                
                % Verificar se há imagens na pasta, se não, exibir erro
                if isempty(arquivoImagem)
                    errordlg('A pasta não contém imagens.', 'Erro');
                    return;
                end

                % Limpar variáveis e processar todas imagens
                limparVariaveis(app);
                processarTodasImagens(app);
            
            % Se a pasta não existir, exibir mensagem de erro 
            else
                errordlg('Pasta de imagens não encontrada.', 'Erro');
            end
        end

        % Button pushed function: BotaoRN
        function BotaoRNButtonPushed(app, event)
            % Se não selecionou nenhuma imagem, mostrar erro em tela
            if isempty(app.ImagemAtual)
                errordlg('Nenhuma imagem foi carregada, por favor, selecione uma imagem.', 'Erro');
                return;
            end
            
            % Se selecionou, limpar as variáveis e classificar a imagem
            limparVariaveis(app);
            processarImagemRN(app, app.ImagemAtual);
        end

        % Button pushed function: BotaoRNTodasimagens
        function BotaoRNTodasimagensButtonPushed(app, event)
            % Se pasta existir, entra na condição
            if isfolder(app.CaminhoPastaImagens)
                arquivoImagem = dir(fullfile(app.CaminhoPastaImagens, '*.jpg'));
                
                % Verificar se há imagens na pasta, se não, exibir erro
                if isempty(arquivoImagem)
                    errordlg('A pasta não contém imagens.', 'Erro');
                    return;
                end

                % Limpar variáveis e classificar todas imagens
                limparVariaveis(app);
                classificarTodasImagens(app);
            
            % Se a pasta não existir, exibir mensagem de erro 
            else
                errordlg('Pasta de imagens não encontrada.', 'Erro');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControledequalidadeemlimesUIFigure = uifigure('Visible', 'off');
            app.ControledequalidadeemlimesUIFigure.Name = 'Controle de qualidade em limões';
            app.listaImagens = uilistbox(app.ControledequalidadeemlimesUIFigure);
            app.listaImagens.ValueChangedFcn = createCallbackFcn(app, @listaImagensValueChanged, true);
            app.ImagemOriginal = uiaxes(app.ControledequalidadeemlimesUIFigure);
            title(app.ImagemOriginal, 'Original')
            xlabel(app.ImagemOriginal, '')
            ylabel(app.ImagemOriginal, '')
            app.BotaoManual = uibutton(app.ControledequalidadeemlimesUIFigure, 'push');
            app.BotaoManual.ButtonPushedFcn = createCallbackFcn(app, @BotaoManualPushed, true);
            app.BotaoManual.Text = '1 Imagem';
            app.textoResultado = uilabel(app.ControledequalidadeemlimesUIFigure);
            app.textoResultado.Text = '';
            app.ImagemFinal = uiaxes(app.ControledequalidadeemlimesUIFigure);
            title(app.ImagemFinal, 'Final')
            xlabel(app.ImagemFinal, '')
            ylabel(app.ImagemFinal, '')
            app.BotaoRN = uibutton(app.ControledequalidadeemlimesUIFigure, 'push');
            app.BotaoRN.ButtonPushedFcn = createCallbackFcn(app, @BotaoRNButtonPushed, true);
            app.BotaoRN.Text = '1 Imagem';
            app.ResultadodaoperaoLabel = uilabel(app.ControledequalidadeemlimesUIFigure);
            app.ResultadodaoperaoLabel.Text = 'Resultado da operação:';
            app.TituloLabel = uilabel(app.ControledequalidadeemlimesUIFigure);
            app.TituloLabel.Text = 'Controle de qualidade em limões';
            app.BotoesMorfoLabel = uilabel(app.ControledequalidadeemlimesUIFigure);
            app.BotoesMorfoLabel.Text = 'Detecção por operações morfológicas:';
            app.TituloIcone = uiimage(app.ControledequalidadeemlimesUIFigure);
            app.TituloIcone.ImageSource = 'lemon_122997.png';
            app.listaImagensLabel = uilabel(app.ControledequalidadeemlimesUIFigure);
            app.listaImagensLabel.Text = 'Selecione uma imagem:';
            app.BotaoManualTodasimagens = uibutton(app.ControledequalidadeemlimesUIFigure, 'push');
            app.BotaoManualTodasimagens.ButtonPushedFcn = createCallbackFcn(app, @BotaoManualTodasimagensButtonPushed, true);
            app.BotaoManualTodasimagens.Text = 'Todas imagens';
            app.BotoesRNLabel = uilabel(app.ControledequalidadeemlimesUIFigure);
            app.BotoesRNLabel.Text = 'Detecção por rede neural:';
            app.BotaoRNTodasimagens = uibutton(app.ControledequalidadeemlimesUIFigure, 'push');
            app.BotaoRNTodasimagens.ButtonPushedFcn = createCallbackFcn(app, @BotaoRNTodasimagensButtonPushed, true);
            app.BotaoRNTodasimagens.Text = 'Todas imagens';
            % Show the figure after all components are created
            app.ControledequalidadeemlimesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrabalhoFinal_Visao_Augusto_Gabriel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControledequalidadeemlimesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControledequalidadeemlimesUIFigure)
        end
    end
end